classdef Tichphann < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        InputMethodButtonGroup  matlab.ui.container.ButtonGroup
        FunctionButton          matlab.ui.control.RadioButton
        XYMethodButton          matlab.ui.control.RadioButton
        ResultLabel             matlab.ui.control.Label
        FxEditField             matlab.ui.control.EditField
        FxEditFieldLabel        matlab.ui.control.Label
        YEditField              matlab.ui.control.EditField
        YEditFieldLabel         matlab.ui.control.Label
        XEditField              matlab.ui.control.EditField
        XEditFieldLabel         matlab.ui.control.Label
        MethodDropDown          matlab.ui.control.DropDown
        MethodDropDownLabel     matlab.ui.control.Label
        NEditField              matlab.ui.control.EditField
        NEditFieldLabel         matlab.ui.control.Label
        LowerEditField          matlab.ui.control.EditField
        LowerEditFieldLabel     matlab.ui.control.Label
        UpperEditField          matlab.ui.control.EditField
        UpperEditFieldLabel     matlab.ui.control.Label
        CalculateButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function InputMethodButtonGroupButtonDown(app, event)

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if app.XYMethodButton.Value
                X = str2double(app.XEditField.Value);
                Y = str2double(app.YEditField.Value);
            else
                equation = app.FxEditField.Value;
                X = linspace(str2num(app.LowerEditField.Value), str2num(app.UpperEditField.Value), 100);
                % Chuyển đổi biểu thức thành đối tượng sym
                symEquation = str2sym(equation);
                if isempty(symEquation)
                    errordlg('Invalid equation. Please enter a valid mathematical expression.');
                    return;
                end
                % Thay thế biến độc lập trong biểu thức
                f = str2func(['@(x)' equation])

                % Tính toán giá trị của biểu thức đã thay thế
                Y = arrayfun(f, X);
                if any(isnan(Y))
                    errordlg('Invalid equation. Please enter a valid mathematical expression.');
                    return;
                end
            end

            method = app.MethodDropDown.Value;
            a = str2double(app.LowerEditField.Value);
            b = str2double(app.UpperEditField.Value);
            N = str2double(app.NEditField.Value);
            if any(isnan([a, b, N]))
                errordlg('Invalid input. Please enter valid numeric values for Lower, Upper, and N.');
                return;
            end

            if isempty(X) || isempty(Y) || isnan(a) || isnan(b) || isnan(N)
                errordlg('Invalid input. Please enter valid numeric values.');
                return;
            end

            switch method
                case 'Trapezoidal'
                    result = trapezoidalRule(X, Y, a, b, N);
                case 'Simpson13'
                    result = simpson13Rule(X, Y, a, b, N);
                case 'Simpson38'
                    result = simpson38Rule(X, Y, a, b, N);
                otherwise
                    errordlg('Invalid integration method. Please select a valid method.');
                    return;
            end
            function result = trapezoidalRule(X, Y, a, b, N)
                    h = (b - a) / N;
                    result = (h / 2) * (Y(1) + 2 * sum(Y(2:end-1)) + Y(end));
            end


            function result = simpson13Rule(X, Y, a, b, N)
                    h = (b - a) / N;
                    result = 0;
                    for i = 1:N
                        x = a + (i - 1) * h;
                        y = Y(i);
                        % Áp dụng trọng số tương ứng dựa trên chỉ số i
                        if i == 1 || i == N
                            result = result + y;
                        elseif mod(i, 2) == 0
                            result = result + 4 * y;
                        else
                            result = result + 2 * y;
                        end
                    end
                    result = double(h / 3 * result);
            end

            function result = simpson38Rule(X, Y, a, b, N)
                    h = (b - a) / (N - 1);
                    sum = 0;
                    for i = 1:(N - 1)
                        x = a + i * h;
                        if mod(i, 3) == 0
                            sum = sum + 2 * Y(i);
                        else
                            sum = sum + 3 * Y(i);
                        end
                    end

            result = 3 * h / 8 * (Y(1) + sum + Y(end));
            end
    
            app.ResultLabel.Text = sprintf('Result: %.7f', result);
        end

        % Selection changed function: InputMethodButtonGroup
        function InputMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.InputMethodButtonGroup.SelectedObject;
            if app.XYMethodButton.Value
                app.XEditField.Visible = 'on';
                app.YEditField.Visible = 'on';
                app.FxEditField.Visible = 'off';
            else
                app.XEditField.Visible = 'off';
                app.YEditField.Visible = 'off';
                app.FxEditField.Visible = 'on';
            end 
        end

        % Button down function: InputMethodButtonGroup
        function InputMethodButtonGroupButtonDown2(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UpperEditFieldLabel = uilabel(app.UIFigure);
            app.UpperEditFieldLabel.Text = 'Upper';
            app.UpperEditField = uieditfield(app.UIFigure, 'text');
            app.UpperEditField.Value = '0.8';
            app.LowerEditFieldLabel = uilabel(app.UIFigure);
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditField = uieditfield(app.UIFigure, 'text');
            app.LowerEditField.Value = '0';
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'text');
            app.NEditField.Value = '10';
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'Trapezoidal', 'Simpson13', 'Simpson38'};
            app.MethodDropDown.Value = 'Trapezoidal';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'text');
            app.XEditField.Value = '0,12,22,32,36,4,44,54,64,7,8';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'text');
            app.YEditField.Value = '0.200,1.309,1.305,1.743,2.074,2.456,2.843,3.507,3.181,2.363,0.232';
            app.FxEditFieldLabel = uilabel(app.UIFigure);
            app.FxEditFieldLabel.Text = 'F(x)';
            app.FxEditField = uieditfield(app.UIFigure, 'text');
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.InputMethodButtonGroup = uibuttongroup(app.UIFigure);
            app.InputMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputMethodButtonGroupSelectionChanged, true);
            app.InputMethodButtonGroup.Title = 'Input Method Button Group';
            app.InputMethodButtonGroup.ButtonDownFcn = createCallbackFcn(app, @InputMethodButtonGroupButtonDown2, true);
            app.XYMethodButton = uiradiobutton(app.InputMethodButtonGroup);
            app.XYMethodButton.Text = 'XY Method';
            app.XYMethodButton.Value = true;
            app.FunctionButton = uiradiobutton(app.InputMethodButtonGroup);
            app.FunctionButton.Text = 'Function';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tichphann

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end