classdef giaodienhoiquy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TinhvaVeButton                matlab.ui.control.Button
        KetquadudoanLabel             matlab.ui.control.Label
        GiatriXEditField              matlab.ui.control.NumericEditField
        GitrdonEditFieldLabel         matlab.ui.control.Label
        KetquaphuongtrinhhoiquyLabel  matlab.ui.control.Label
        ChonphuongphapDropDown        matlab.ui.control.DropDown
        ChnphngphpDropDownLabel       matlab.ui.control.Label
        NhapmangYEditField            matlab.ui.control.EditField
        NhpmngYEditFieldLabel         matlab.ui.control.Label
        NhapmangXEditField            matlab.ui.control.EditField
        NhpmngXEditFieldLabel         matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TinhvaVeButton
        function TinhvaVeButtonPushed(app, event)
           xa = app.NhapmangXEditField.Value;
           x_number = strsplit(xa, ','); % Chia chuỗi thành các phần tử bằng dấu phẩy và khoảng trắng
           x = str2double(x_number); % Chuyển các phần tử của mảng chuỗi thành số
           %%
           ya = app.NhapmangYEditField.Value;
           y_number = strsplit(ya, ','); % Chia chuỗi thành các phần tử bằng dấu phẩy và khoảng trắng
           y = str2double(y_number); % Chuyển các phần tử của mảng chuỗi thành số
           %
           PP_Hquy = app.ChonphuongphapDropDown.Value;
           %
           giatridudoan = app.GiatriXEditField.Value;
           [hs_x, hs_y] = HquyTT(x, y, PP_Hquy, giatridudoan);
           
            function [hs_x, hs_y] = HquyTT(x, y, phuongphap, giatridudoan)
               if(phuongphap == "Tuyến tính")
                    degree = 1;
                    coefficients = polyfit(x, y, degree);
                    % Lấy các hệ số của đường thẳng hồi quy
                    slope = coefficients(1); % Hệ số góc
                    intercept = coefficients(2); % Hệ số giao intercept
                    hs_x = slope;
                    hs_y = intercept;
                    app.KetquaphuongtrinhhoiquyLabel.Text = sprintf('Phương trình hồi quy: y = %.2fx + %.2f\n', hs_x, hs_y);
                    x_values = 1:0.1:9; % Tạo dãy x để vẽ đường thẳng hồi quy
                    y_values = slope .* x_values + intercept; % Tính các giá trị y tương ứng
                    plot(app.UIAxes, x_values, y_values);
                    y_true = hs_x * giatridudoan + hs_y;
                    app.KetquadudoanLabel.Text = sprintf('Giá trị dự đoán khi x = %.2f là: %.5f', giatridudoan, y_true);
               elseif(phuongphap == "Hàm mũ")
                    log_x = log(x);
                    log_y = log(y);
                    % Thực hiện hồi quy tuyến tính trên dữ liệu đã chuyển đổi
                    coefficients = polyfit(log_x, log_y, 1); % Hồi quy tuyến tính bậc 1
                    % Lấy các hệ số của đường thẳng hồi quy
                    hs_x = coefficients(1); % Hệ số góc
                    hs_y = exp(coefficients(2)); % Hệ số a
                    app.KetquaphuongtrinhhoiquyLabel.Text = sprintf('Phương trình hồi quy theo hàm mũ: y = %.2f * x^%.2f\n', hs_x, hs_y);
                    x_values = 1:0.1:9; % Tạo dãy x để vẽ đường thẳng hồi quy
                    y_values = hs_x .* (x_values .^ hs_y); % Tính các giá trị y tương ứng
                    plot(app.UIAxes, x_values, y_values);
                    y_true = hs_x * (giatridudoan ^ hs_y);
                    app.KetquadudoanLabel.Text = sprintf('Giá trị dự đoán khi x = %.2f là: %.5f', giatridudoan, y_true);
                elseif(phuongphap == "Mũ e")
                    log_y = log(y);
                    % Thực hiện hồi quy tuyến tính trên dữ liệu đã chuyển đổi
                    coefficients = polyfit(x, log_y, 1); % Hồi quy tuyến tính bậc 1
                    % Lấy các hệ số của đường thẳng hồi quy
                    hs_x = coefficients(1); % Hệ số góc
                    hs_y = exp(coefficients(2)); % Hệ số a
                    app.KetquaphuongtrinhhoiquyLabel.Text = sprintf('Phương trình hồi quy theo dạng e mũ: y = %.2f * e^(%.2fx)\n', hs_x, hs_y);
                    x_values = 1:0.1:9; % Tạo dãy x để vẽ đường thẳng hồi quy
                    y_values = hs_x .* (exp(x_values .* hs_y)); % Tính các giá trị y tương ứng
                    plot(app.UIAxes, x_values, y_values);
                    y_true = hs_x * exp(giatridudoan * hs_y);
                    app.KetquadudoanLabel.Text = sprintf('Giá trị dự đoán khi x = %.2f là: %.5f', giatridudoan, y_true);
               else
                   hs_x = NaN; % Trả về NaN nếu không thể tính đạo hàm trung tâm
                   hs_y = NaN;
                end
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Đồ thị hàm hồi quy')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpmngXEditFieldLabel = uilabel(app.UIFigure);
            app.NhpmngXEditFieldLabel.Text = 'Nhập mảng X';
            app.NhapmangXEditField = uieditfield(app.UIFigure, 'text');
            app.NhapmangXEditField.Value = '1,2,3,4,5,6,7,8,9';
            app.NhpmngYEditFieldLabel = uilabel(app.UIFigure);
            app.NhpmngYEditFieldLabel.Text = 'Nhập mảng Y';
            app.NhapmangYEditField = uieditfield(app.UIFigure, 'text');
            app.NhapmangYEditField.Value = '1,1.5,2,3,4,5,8,10,13';
            app.ChnphngphpDropDownLabel = uilabel(app.UIFigure);
            app.ChnphngphpDropDownLabel.Text = 'Chọn phương pháp';
            app.ChonphuongphapDropDown = uidropdown(app.UIFigure);
            app.ChonphuongphapDropDown.Items = {'Tuyến tính', 'Hàm mũ', 'Mũ e'};
            app.ChonphuongphapDropDown.Value = 'Tuyến tính';
            app.KetquaphuongtrinhhoiquyLabel = uilabel(app.UIFigure);
            app.KetquaphuongtrinhhoiquyLabel.Text = 'Kết quả phương trình hồi quy';
            app.GitrdonEditFieldLabel = uilabel(app.UIFigure);
            app.GitrdonEditFieldLabel.Text = 'Giá trị dự đoán';
            app.GiatriXEditField = uieditfield(app.UIFigure, 'numeric');
            app.GiatriXEditField.Value = 12;
            app.KetquadudoanLabel = uilabel(app.UIFigure);
            app.KetquadudoanLabel.Text = 'Kết quả dự đoán';
            app.TinhvaVeButton = uibutton(app.UIFigure, 'push');
            app.TinhvaVeButton.ButtonPushedFcn = createCallbackFcn(app, @TinhvaVeButtonPushed, true);
            app.TinhvaVeButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = giaodienhoiquy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end